classdef referencesignalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        CenterFrequencySliderLabel   matlab.ui.control.Label
        CenterFrequencySlider        matlab.ui.control.Slider
        ConvolutionSizeSliderLabel   matlab.ui.control.Label
        ConvolutionSizeSlider        matlab.ui.control.Slider
        GoButton                     matlab.ui.control.Button
        StartingLocationSliderLabel  matlab.ui.control.Label
        StartingLocationSlider       matlab.ui.control.Slider
        EndingLocationSliderLabel    matlab.ui.control.Label
        EndingLocationSlider         matlab.ui.control.Slider
    end


    properties (Access = public)
        datastring % Description
        xdata % Description
        ydata
        centerFreq % Description
        convValue % Description
        startloc
        endloc
        resultingy
        resultingyend
        destdirectory

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datastring, destdirectory)
            % how to import components after starting app.datastring = 
            app.datastring = datastring;
            app.centerFreq = 3250;
            app.convValue = 400;
            app.startloc = 0;
            if destdirectory == 0
                app.destdirectory = 0;
                
            elseif exist('destdirectory', 'var')
                app.destdirectory = destdirectory;
            else
                try
                    mkdir(destdirectory);
                    app.destdirectory = destdirectory;
                catch
                    warning('Problem creating directory. Not plotting');
                    app.destdirectory = 0;
                end
            end

            
            app.resultingy = 0;
            app.resultingyend = 0;
         
            app.ydata =  preparingreferencesignal(app.datastring, app.centerFreq, app.convValue);
            app.xdata = 1:length(app.ydata);
             app.endloc = length(app.xdata);
            plot(app.UIAxes,app.xdata,app.ydata, 'b');
            hold(app.UIAxes);
            line(app.UIAxes, app.startloc,app.resultingy,'linestyle','none','marker','o','markeredgecolor','g','Tag','MyMarker');
            line(app.UIAxes, app.endloc,app.resultingyend,'linestyle','none','marker','o','markeredgecolor','r','Tag','MyMarker');
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
%              D = guidatabase(string, app.centerFreq, app.convValue, app.startloc, app.endloc);
%              assignin('base', strcat(app.datastring, 'D'), D);
                assignin('base', 'centerFreq', app.centerFreq);
                assignin('base', 'convValue',app.convValue);
                assignin('base', 'startloc', app.startloc);
                assignin('base', 'endloc', app.endloc);
                if app.destdirectory ~= 0
                    h = figure;
                    h.Visible = 'off';
                    plot(app.xdata,app.ydata, 'b');
                    hold on;
                    line(app.startloc,app.resultingy,'linestyle','none','marker','o','markeredgecolor','g','Tag','MyMarker');
                    line(app.endloc,app.resultingyend,'linestyle','none','marker','o','markeredgecolor','r','Tag','MyMarker');
                   
                    thisimage1 = strcat(app.datastring(end-10:end-4), '.jpg');
                    fulldestination = fullfile(app.destdirectory, thisimage1);
                    saveas(h, fulldestination);
                end
                closereq
        end

        % Value changing function: CenterFrequencySlider
        function CenterFrequencySliderValueChanging(app, event)
             
            app.centerFreq = event.Value;
            datatobeplotted= preparingreferencesignal(app.datastring, app.centerFreq, app.convValue);
            app.ydata = datatobeplotted;
            app.xdata = 1:length(app.ydata);
            plot(app.UIAxes,app.xdata,app.ydata, 'b');
            hold(app.UIAxes);
            line(app.UIAxes, app.startloc,app.resultingy,'linestyle','none','marker','o','markeredgecolor','g','Tag','MyMarker');
            line(app.UIAxes, app.endloc,app.resultingyend,'linestyle','none','marker','o','markeredgecolor','r','Tag','MyMarker');
            
        end

        % Value changing function: ConvolutionSizeSlider
        function ConvolutionSizeSliderValueChanging(app, event)
            app.convValue = round(event.Value);
            
            datatobeplotted= preparingreferencesignal(app.datastring, app.centerFreq, app.convValue);
            app.ydata = datatobeplotted;
            app.xdata = 1:length(app.ydata);
            plot(app.UIAxes,app.xdata,app.ydata, 'b');
            hold(app.UIAxes);
            line(app.UIAxes, app.startloc,app.resultingy,'linestyle','none','marker','o','markeredgecolor','g','Tag','MyMarker');
            line(app.UIAxes, app.endloc,app.resultingyend,'linestyle','none','marker','o','markeredgecolor','r','Tag','MyMarker');
        end

        % Value changing function: EndingLocationSlider
        function EndingLocationSliderValueChanging(app, event)
    
            app.endloc = round(event.Value);
            app.resultingyend = app.ydata(app.endloc);
            plot(app.UIAxes,app.xdata,app.ydata, 'b');
            hold(app.UIAxes);
            line(app.UIAxes, app.startloc,app.resultingy,'linestyle','none','marker','o','markeredgecolor','g','Tag','MyMarker');
            line(app.UIAxes, app.endloc,app.resultingyend,'linestyle','none','marker','o','markeredgecolor','r','Tag','MyMarker');
    

        end

        % Value changing function: StartingLocationSlider
        function StartingLocationSliderValueChanging(app, event)
 
            app.startloc = round(event.Value);
            app.resultingy = app.ydata(app.startloc);
            plot(app.UIAxes,app.xdata,app.ydata, 'b');
            hold(app.UIAxes);
            line(app.UIAxes, app.startloc,app.resultingy,'linestyle','none','marker','o','markeredgecolor','g','Tag','MyMarker');
            line(app.UIAxes, app.endloc,app.resultingyend,'linestyle','none','marker','o','markeredgecolor','r','Tag','MyMarker');
    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Amplitude')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CenterFrequencySliderLabel = uilabel(app.UIFigure);
            app.CenterFrequencySliderLabel.Text = 'Center Frequency';
            app.CenterFrequencySlider = uislider(app.UIFigure);
            app.CenterFrequencySlider.Limits = [2400 3500];
            app.CenterFrequencySlider.ValueChangingFcn = createCallbackFcn(app, @CenterFrequencySliderValueChanging, true);
            app.CenterFrequencySlider.Value = 3250;
            app.ConvolutionSizeSliderLabel = uilabel(app.UIFigure);
            app.ConvolutionSizeSliderLabel.Text = 'Convolution Size';
            app.ConvolutionSizeSlider = uislider(app.UIFigure);
            app.ConvolutionSizeSlider.Limits = [200 700];
            app.ConvolutionSizeSlider.ValueChangingFcn = createCallbackFcn(app, @ConvolutionSizeSliderValueChanging, true);
            app.ConvolutionSizeSlider.Value = 400;
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.StartingLocationSliderLabel = uilabel(app.UIFigure);
            app.StartingLocationSliderLabel.Text = 'Starting Location';
            app.StartingLocationSlider = uislider(app.UIFigure);
            app.StartingLocationSlider.Limits = [0 50000];
            app.StartingLocationSlider.ValueChangingFcn = createCallbackFcn(app, @StartingLocationSliderValueChanging, true);
            app.StartingLocationSlider.Value = 20000;
            app.EndingLocationSliderLabel = uilabel(app.UIFigure);
            app.EndingLocationSliderLabel.Text = 'Ending Location';
            app.EndingLocationSlider = uislider(app.UIFigure);
            app.EndingLocationSlider.Limits = [0 80000];
            app.EndingLocationSlider.ValueChangingFcn = createCallbackFcn(app, @EndingLocationSliderValueChanging, true);
            app.EndingLocationSlider.Value = 40000;
        end
    end

    methods (Access = public)

        % Construct app
        function app = referencesignalApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end